classdef Start_develop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BACKButton                    matlab.ui.control.Button
        STARTButton                   matlab.ui.control.Button
        DEBUGButton                   matlab.ui.control.Button
        SignalsonplotLabel            matlab.ui.control.Label
        errormodelCheckBox            matlab.ui.control.CheckBox
        errorobjectCheckBox           matlab.ui.control.CheckBox
        OutputSignalobjectCheckBox    matlab.ui.control.CheckBox
        SteeringSignalobjectCheckBox  matlab.ui.control.CheckBox
        OutputSignalmodelCheckBox     matlab.ui.control.CheckBox
        SteeringSignalmodelCheckBox   matlab.ui.control.CheckBox
        ReferenceSignalCheckBox       matlab.ui.control.CheckBox
        KdEditField                   matlab.ui.control.NumericEditField
        KdEditFieldLabel              matlab.ui.control.Label
        KiEditField                   matlab.ui.control.NumericEditField
        KiEditFieldLabel              matlab.ui.control.Label
        KpEditField                   matlab.ui.control.NumericEditField
        KpEditFieldLabel              matlab.ui.control.Label
        FinalValueSlider              matlab.ui.control.Slider
        FinalValueSliderLabel         matlab.ui.control.Label
        ControllerSpecsLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        State
        UdpPort
        Data
        Timer
        Message
        Values
        Cnt
    end
    
methods (Access = public)
    function Loop_Fcn(app, varargin)
        if app.State == 1
            newData = read(app.UdpPort, 1);
            app.Data = newData;
            app.Message(end+1) = app.Data;
            if app.Data(end) == 59
                app.Message = [];
                app.Values(end+1,1) = 0;
            end
            if app.Data(end) == 44
                app.Values(end,mod(app.Cnt, 3)+1) = char(app.Message(1:end-1));
            end
            app.Cnt = app.Cnt + 1;
        end
        if mod(app.Cnt,3)
            plot(app.UIAxes,app.Values(:,1)); hold on;
            plot(app.UIAxes,app.Values(:,2),'Color','r');
            plot(app.UIAxes,app.Values(:,3),'Color','g'); hold off;
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.State = 2;
            app.Message = [];
            app.Values = 0;
            app.Cnt = 0;
            app.UdpPort = udpport("LocalPort", 25565);
            app.Timer = timer("ExecutionMode","fixedRate","Period",0.1,"BusyMode","queue","TimerFcn",@(varargin)app.Loop_Fcn);            
        end

        % Button pushed function: DEBUGButton
        function DEBUGButtonPushed(app, event)
            fclose(app.UdpPort);
            stop(app.Timer);
            app1_develop;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.State == 1
                app.State = 2;
            elseif app.State == 2
                app.State = 1;
            end
            if app.State == 1
                fopen(app.UdpPort);
                app.STARTButton.Text = "STOP";
                start(app.Timer);
            elseif app.State == 2
                fclose(app.UdpPort);
                app.STARTButton.Text = "START";
                stop(app.Timer);
            end
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            delete(app);
            aplication;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'poznan-university-of-technology.png');
            app.ControllerSpecsLabel = uilabel(app.UIFigure);
            app.ControllerSpecsLabel.Text = 'Controller Specs:';
            app.FinalValueSliderLabel = uilabel(app.UIFigure);
            app.FinalValueSliderLabel.Text = 'Final Value';
            app.FinalValueSlider = uislider(app.UIFigure);
            app.FinalValueSlider.Limits = [0 360];
            app.FinalValueSlider.MajorTicks = [0 90 180 270 360];
            app.FinalValueSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210 215 220 225 230 235 240 245 250 255 260 265 270 275 280 285 290 295 300 305 310 315 320 325 330 335 340 345 350 355 360];
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferenceSignalCheckBox = uicheckbox(app.UIFigure);
            app.ReferenceSignalCheckBox.Text = 'Reference Signal';
            app.SteeringSignalmodelCheckBox = uicheckbox(app.UIFigure);
            app.SteeringSignalmodelCheckBox.Text = 'Steering Signal (model)';
            app.OutputSignalmodelCheckBox = uicheckbox(app.UIFigure);
            app.OutputSignalmodelCheckBox.Text = 'Output Signal (model)';
            app.SteeringSignalobjectCheckBox = uicheckbox(app.UIFigure);
            app.SteeringSignalobjectCheckBox.Text = 'Steering Signal (object)';
            app.OutputSignalobjectCheckBox = uicheckbox(app.UIFigure);
            app.OutputSignalobjectCheckBox.Text = 'Output Signal (object)';
            app.errorobjectCheckBox = uicheckbox(app.UIFigure);
            app.errorobjectCheckBox.Text = 'error (object)';
            app.errormodelCheckBox = uicheckbox(app.UIFigure);
            app.errormodelCheckBox.Text = 'error (model)';
            app.SignalsonplotLabel = uilabel(app.UIFigure);
            app.SignalsonplotLabel.Text = 'Signals on plot:';
            app.DEBUGButton = uibutton(app.UIFigure, 'push');
            app.DEBUGButton.ButtonPushedFcn = createCallbackFcn(app, @DEBUGButtonPushed, true);
            app.DEBUGButton.Text = 'DEBUG';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_develop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end